classdef DspGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure            % UI Figure
        plottime         matlab.ui.control.UIAxes    % Time Domain
        plotfrequency    matlab.ui.control.UIAxes    % Frequency Domain
        Buttonbrouse     matlab.ui.control.Button    % Browse
        LabelEditField   matlab.ui.control.Label     % File
        inputfile        matlab.ui.control.EditField
        Label7           matlab.ui.control.Label     % Gain1
        Slider1          matlab.ui.control.Slider    % [-12 12]
        Label8           matlab.ui.control.Label     % Gain2
        Slider2          matlab.ui.control.Slider    % [-12 12]
        Label9           matlab.ui.control.Label     % Gain3
        Slider3          matlab.ui.control.Slider    % [-12 12]
        Label10          matlab.ui.control.Label     % Gain4
        Slider4          matlab.ui.control.Slider    % [-12 12]
        Label11          matlab.ui.control.Label     % Gain5
        Slider5          matlab.ui.control.Slider    % [-12 12]
        Label12          matlab.ui.control.Label     % Gain6
        Slider6          matlab.ui.control.Slider    % [-12 12]
        Label13          matlab.ui.control.Label     % Gain7
        Slider7          matlab.ui.control.Slider    % [-12 12]
        Label14          matlab.ui.control.Label     % Gain8
        Slider8          matlab.ui.control.Slider    % [-12 12]
        Label15          matlab.ui.control.Label     % Gain9
        Slider9          matlab.ui.control.Slider    % [-12 12]
        LabelDropDown    matlab.ui.control.Label     % Filter Type
        filtertype       matlab.ui.control.DropDown  % FIR Rectanguler, IIR
        LabelDropDown2   matlab.ui.control.Label     % Rate
        rate             matlab.ui.control.DropDown  % Normal, Double, Half
        Buttonplayinput  matlab.ui.control.Button    % Play Input
        Buttoncomposite  matlab.ui.control.Button    % Plot Composite
        Buttonband       matlab.ui.control.Button    % Plot Band
        Buttonoriginal   matlab.ui.control.Button    % Plot Original
        LabelDropDown3   matlab.ui.control.Label     %  Band
        band             matlab.ui.control.DropDown  % 0-170, 170-310, 310-...
        Buttonplayoutput matlab.ui.control.Button    % Play Output
        Buttonok         matlab.ui.control.Button    % OK
        Buttonfilter     matlab.ui.control.Button    % Filter virtualization
        Buttonbase       matlab.ui.control.Button    % Base
        stopbutton       matlab.ui.control.Button    % stop
    end


    properties (Access = public)
        Fs = 48000;
        flag=false;
           ratefs=48000;
           filter_type=1;
           bandnum=1;
         X,Y;
         H_1;
         H_2;
         H_3;
         H_4;
         H_5;
         H_6;
         H_7;
         H_8;
         H_9;
         y_1;
         y_2;
         y_3;
         y_4;
         y_5;
         y_6;
         y_7;
         y_8;
          y_9;
         y_11;
         y_22;
         y_33;
         y_44;
         y_55;
         y_66;
         y_77;
         y_88;
          y_99;
             g_1=1;
             g_2=1;
             g_3=1;
             g_4=1;
             g_5=1;
             g_6=1;
             g_7=1;
             g_8=1;
             g_9=1; 
           
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % inputfile value changed function
        function inputfileValueChanged(app)
            value = app.inputfile.Value;
            [app.X,app.Fs]=audioread(value);
            app.ratefs=app.Fs;
        end

        % Buttonbrouse button pushed function
        function ButtonbrouseButtonPushed(app)
            [file,path] = uigetfile('*.wav');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            file_name=strcat(path,file);
            [app.X,app.Fs]=audioread(file_name);
            app.ratefs=app.Fs;
        end

        % rate value changed function
        function rateValueChanged(app, event)
            value = app.rate.Value;
            switch value
                case 'Normal' 
                    app.ratefs=app.Fs;
                case 'Double'
                    app.ratefs=app.Fs*0.5;
                case 'Half'  
                    app.ratefs=app.Fs*2;
           end
        end

        % Buttonplayinput button pushed function
        function ButtonplayinputButtonPushed(app)
          sound(app.X, app.ratefs);
        end

        % Buttonoriginal button pushed function
        function ButtonoriginalButtonPushed(app)
         t=linspace(0, length(app.X)/app.Fs,length(app.X)); 
         plot(app.plottime,t, app.X); 
         Nfft = length(app.X);
        f=linspace(0, app.Fs, Nfft);
        G=abs(fft(app.X, Nfft));
        plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
        end

        % filtertype value changed function
        function filtertypeValueChanged(app, event)
            value = app.filtertype.Value;
            switch value
                case 'FIR Rectanguler' 
                    app.filter_type=1;
                case 'IIR'
                   app.filter_type=2;
            end 
            
        end

        % Buttonband button pushed function
        function ButtonbandButtonPushed(app)
            if(app.flag==true)
            switch app.bandnum
                case 1 
                     t=linspace(0, length(app.y_1)/app.Fs,length(app.y_1));
                    plot(app.plottime,t, app.y_1); 
                    Nfft = length(app.y_1);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.y_1, Nfft));
                    plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
                case 2 
                     t=linspace(0, length(app.y_2)/app.Fs,length(app.y_2));
                    plot(app.plottime,t, app.y_2); 
                    Nfft = length(app.y_2);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.y_2, Nfft));
                    plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
                case 3 
                     t=linspace(0, length(app.y_3)/app.Fs,length(app.y_3));
                    plot(app.plottime,t, app.y_3); 
                    Nfft = length(app.y_3);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.y_3, Nfft));
                    plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
                case 4 
                     t=linspace(0, length(app.y_4)/app.Fs,length(app.y_4));
                    plot(app.plottime,t, app.y_4); 
                    Nfft = length(app.y_4);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.y_4, Nfft));
                    plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
                case 5 
                     t=linspace(0, length(app.y_5)/app.Fs,length(app.y_5));
                    plot(app.plottime,t, app.y_5); 
                    Nfft = length(app.y_5);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.y_5, Nfft));
                    plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
               case 6 
                     t=linspace(0, length(app.y_6)/app.Fs,length(app.y_6));
                    plot(app.plottime,t, app.y_6); 
                    Nfft = length(app.y_6);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.y_6, Nfft));
                    plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
               case 7 
                     t=linspace(0, length(app.y_7)/app.Fs,length(app.y_7));
                    plot(app.plottime,t, app.y_7); 
                    Nfft = length(app.y_7);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.y_7, Nfft));
                    plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
               case 8 
                     t=linspace(0, length(app.y_8)/app.Fs,length(app.y_8));
                    plot(app.plottime,t, app.y_8); 
                    Nfft = length(app.y_8);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.y_8, Nfft));
                    plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
               case 9 
                     t=linspace(0, length(app.y_9)/app.Fs,length(app.y_9));
                    plot(app.plottime,t, app.y_9); 
                    Nfft = length(app.y_9);
                    f=linspace(0, app.Fs, Nfft);
                    G=abs(fft(app.y_9, Nfft));
                    plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
            end
                
            end
        end

        % Buttonok button pushed function
        function ButtonokButtonPushed(app)

     if app.filter_type == 1
         app.H_1=rectangular1(app.Fs);
         app.H_2=rectangular2(app.Fs);
         app.H_3=rectangular3(app.Fs);
         app.H_4=rectangular4(app.Fs);
         app.H_5=rectangular5(app.Fs);
         app.H_6=rectangular6(app.Fs);
         app.H_7=rectangular7(app.Fs);
         app.H_8=rectangular8(app.Fs);
         app.H_9=rectangular9(app.Fs);
     end
if app.filter_type == 2
         app.H_1=IIR_1(app.Fs);
         app.H_2=IIR_2(app.Fs);
         app.H_3=IIR_3(app.Fs);
         app.H_4=IIR_4(app.Fs);
         app.H_5=IIR_5(app.Fs);
         app.H_6=IIR_6(app.Fs);
         app.H_7=IIR_7(app.Fs);
         app.H_8=IIR_8(app.Fs);
         app.H_9=IIR_9(app.Fs);
end

    app.flag=true;
app.y_1=filter(app.H_1, app.X);
app.y_2=filter(app.H_2, app.X);
app.y_3=filter(app.H_3, app.X);
app.y_4=filter(app.H_4, app.X);
app.y_5=filter(app.H_5, app.X);
app.y_6=filter(app.H_6, app.X);
app.y_7=filter(app.H_7, app.X);
app.y_8=filter(app.H_8, app.X);
app.y_9=filter(app.H_9, app.X);


app.y_11=app.y_1*app.g_1;
app.y_22=app.y_2*app.g_2;
app.y_33=app.y_3*app.g_3;
app.y_44=app.y_4*app.g_4;
app.y_55=app.y_5*app.g_5;
app.y_66=app.y_6*app.g_6;
app.y_77=app.y_7*app.g_7;
app.y_88=app.y_8*app.g_8;
app.y_99=app.y_9*app.g_9;

app.Y = app.y_11+app.y_22+app.y_33+app.y_44+app.y_55+app.y_66+app.y_77+app.y_88+app.y_99;
        end

        % band value changed function
        function bandValueChanged(app, event)
            value = app.band.Value;
             switch value
                case '0-170' 
                    app.bandnum=1;
                case '170-310'
                    app.bandnum=2;
                case '310-600'  
                    app.bandnum=3;
                case '600-1000'  
                    app.bandnum=4;
                case '1000-3000'  
                    app.bandnum=5;
                case '3000-6000'  
                    app.bandnum=6;
                case '6000-12000'  
                   app.bandnum=7;
                case '12000-14000'  
                   app.bandnum=8;
                case '14000-16000'  
                   app.bandnum=9;
             end       
        end

        % Slider1 value changed function
        function Slider1ValueChanged(app)
            value = app.Slider1.Value;
            app.g_1=10^(value/20);
        end

        % Slider2 value changed function
        function Slider2ValueChanged(app)
            value = app.Slider2.Value;
            app.g_2=10^(value/20);
        end

        % Slider3 value changed function
        function Slider3ValueChanged(app)
            value = app.Slider3.Value;
            app.g_3=10^(value/20);
        end

        % Slider4 value changed function
        function Slider4ValueChanged(app)
            value = app.Slider4.Value;
            app.g_4=10^(value/20);
        end

        % Slider5 value changed function
        function Slider5ValueChanged(app)
            value = app.Slider5.Value;
            app.g_5=10^(value/20);
        end

        % Slider6 value changed function
        function Slider6ValueChanged(app)
            value = app.Slider6.Value;
            app.g_6=10^(value/20);
        end

        % Slider7 value changed function
        function Slider7ValueChanged(app)
            value = app.Slider7.Value;
            app.g_7=10^(value/20);
        end

        % Slider8 value changed function
        function Slider8ValueChanged(app)
            value = app.Slider8.Value;
            app.g_8=10^(value/20);
        end

        % Slider9 value changed function
        function Slider9ValueChanged(app)
            value = app.Slider9.Value;
            app.g_9=10^(value/20);
        end

        % Buttonplayoutput button pushed function
        function ButtonplayoutputButtonPushed(app)
            sound(app.Y, app.ratefs);
        end

        % Buttoncomposite button pushed function
        function ButtoncompositeButtonPushed(app)
          if(app.flag==true)
            t=linspace(0, length(app.Y)/app.Fs,length(app.Y));
          plot(app.plottime,t, app.Y); 
           Nfft = length(app.Y);
        f=linspace(0, app.Fs, Nfft);
        G=abs(fft(app.Y, Nfft));
        plot(app.plotfrequency,f(1:Nfft/2), G(1:Nfft/2));
          audiowrite('outputcomposite.wav',app.Y,app.Fs);    
          end
        end

        % Buttonfilter button pushed function
        function ButtonfilterButtonPushed(app)
           switch app.bandnum
               case 1
                    grpdelay(app.H_1);
               case 2
                   grpdelay(app.H_2);
               case 3
                   grpdelay(app.H_3);
               case 4
                   grpdelay(app.H_4);
               case 5
                   grpdelay(app.H_5);
               case 6
                   grpdelay(app.H_6);
               case 7
                   grpdelay(app.H_7);
               case 8
                   grpdelay(app.H_8);
               case 9
                   grpdelay(app.H_9);
          end
        end

        % Buttonbase button pushed function
        function ButtonbaseButtonPushed(app)
           
             app.g_1=12;app.g_2=0;app.g_3=0;app.g_4=0;app.g_5=0;app.g_6=0;app.g_7=0;app.g_8=0;app.g_9=0;
               
        end

        % stopbutton button pushed function
        function stopbuttonButtonPushed(app)
             clear sound;
        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            answer = app.Fs;
            app.FeedbackTextArea.Value = answer;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.plottime = uiaxes(app.UIFigure);
            title(app.plottime, 'Time Domain');
            xlabel(app.plottime, 'Time');
            ylabel(app.plottime, 'Sample');
            app.plotfrequency = uiaxes(app.UIFigure);
            title(app.plotfrequency, 'Frequency Domain');
            xlabel(app.plotfrequency, 'Frequency');
            ylabel(app.plotfrequency, 'Spectrum');
            app.Buttonbrouse = uibutton(app.UIFigure, 'push');
            app.Buttonbrouse.ButtonPushedFcn = createCallbackFcn(app, @ButtonbrouseButtonPushed);
            app.Buttonbrouse.Text = 'Browse';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'File';
            app.inputfile = uieditfield(app.UIFigure, 'text');
            app.inputfile.ValueChangedFcn = createCallbackFcn(app, @inputfileValueChanged);
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Gain1';
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.Limits = [-12 12];
            app.Slider1.MajorTickLabels = {'-12db', '-8', '-4', '0', '4', '8', '12db'};
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged);
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'Gain2';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [-12 12];
            app.Slider2.MajorTickLabels = {'-12db', '-8', '-4', '0', '4', '8', '12db'};
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged);
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'Gain3';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [-12 12];
            app.Slider3.MajorTickLabels = {'-12db', '-8', '-4', '0', '4', '8', '12db'};
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged);
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Gain4';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Limits = [-12 12];
            app.Slider4.MajorTickLabels = {'-12db', '-8', '-4', '0', '4', '8', '12db'};
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @Slider4ValueChanged);
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'Gain5';
            app.Slider5 = uislider(app.UIFigure);
            app.Slider5.Limits = [-12 12];
            app.Slider5.MajorTickLabels = {'-12db', '-8', '-4', '0', '4', '8', '12db'};
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanged);
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Text = 'Gain6';
            app.Slider6 = uislider(app.UIFigure);
            app.Slider6.Limits = [-12 12];
            app.Slider6.MajorTickLabels = {'-12db', '-8', '-4', '0', '4', '8', '12db'};
            app.Slider6.ValueChangedFcn = createCallbackFcn(app, @Slider6ValueChanged);
            app.Label13 = uilabel(app.UIFigure);
            app.Label13.Text = 'Gain7';
            app.Slider7 = uislider(app.UIFigure);
            app.Slider7.Limits = [-12 12];
            app.Slider7.MajorTickLabels = {'-12db', '-8', '-4', '0', '4', '8', '12db'};
            app.Slider7.ValueChangedFcn = createCallbackFcn(app, @Slider7ValueChanged);
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Text = 'Gain8';
            app.Slider8 = uislider(app.UIFigure);
            app.Slider8.Limits = [-12 12];
            app.Slider8.MajorTickLabels = {'-12db', '-8', '-4', '0', '4', '8', '12db'};
            app.Slider8.ValueChangedFcn = createCallbackFcn(app, @Slider8ValueChanged);
            app.Label15 = uilabel(app.UIFigure);
            app.Label15.Text = 'Gain9';
            app.Slider9 = uislider(app.UIFigure);
            app.Slider9.Limits = [-12 12];
            app.Slider9.MajorTickLabels = {'-12db', '-8', '-4', '0', '4', '8', '12db'};
            app.Slider9.ValueChangedFcn = createCallbackFcn(app, @Slider9ValueChanged);
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Filter Type';
            app.filtertype = uidropdown(app.UIFigure);
            app.filtertype.Items = {'FIR Rectanguler', 'IIR'};
            app.filtertype.ValueChangedFcn = createCallbackFcn(app, @filtertypeValueChanged, true);
            app.filtertype.Value = 'FIR Rectanguler';
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Rate';
            app.rate = uidropdown(app.UIFigure);
            app.rate.Items = {'Normal', 'Double', 'Half'};
            app.rate.ValueChangedFcn = createCallbackFcn(app, @rateValueChanged, true);
            app.rate.Value = 'Normal';
            app.Buttonplayinput = uibutton(app.UIFigure, 'push');
            app.Buttonplayinput.ButtonPushedFcn = createCallbackFcn(app, @ButtonplayinputButtonPushed);
            app.Buttonplayinput.Text = 'Play Input';
            app.Buttoncomposite = uibutton(app.UIFigure, 'push');
            app.Buttoncomposite.ButtonPushedFcn = createCallbackFcn(app, @ButtoncompositeButtonPushed);
            app.Buttoncomposite.Text = 'Plot Composite';
            app.Buttonband = uibutton(app.UIFigure, 'push');
            app.Buttonband.ButtonPushedFcn = createCallbackFcn(app, @ButtonbandButtonPushed);
            app.Buttonband.Text = 'Plot Band';
            app.Buttonoriginal = uibutton(app.UIFigure, 'push');
            app.Buttonoriginal.ButtonPushedFcn = createCallbackFcn(app, @ButtonoriginalButtonPushed);
            app.Buttonoriginal.Text = 'Plot Original';
            app.LabelDropDown3 = uilabel(app.UIFigure);
            app.LabelDropDown3.Text = ' Band';
            app.band = uidropdown(app.UIFigure);
            app.band.Items = {'0-170', '170-310', '310-600', '600-1000', '1000-3000', '3000-6000', '6000-12000', '12000-14000', '14000-16000'};
            app.band.ValueChangedFcn = createCallbackFcn(app, @bandValueChanged, true);
            app.band.Value = '0-170';
            app.Buttonplayoutput = uibutton(app.UIFigure, 'push');
            app.Buttonplayoutput.ButtonPushedFcn = createCallbackFcn(app, @ButtonplayoutputButtonPushed);
            app.Buttonplayoutput.Text = 'Play Output';
            app.Buttonok = uibutton(app.UIFigure, 'push');
            app.Buttonok.ButtonPushedFcn = createCallbackFcn(app, @ButtonokButtonPushed);
            app.Buttonok.Text = 'OK';
            app.Buttonfilter = uibutton(app.UIFigure, 'push');
            app.Buttonfilter.ButtonPushedFcn = createCallbackFcn(app, @ButtonfilterButtonPushed);
            app.Buttonfilter.Text = 'Filter virtualization';
            app.Buttonbase = uibutton(app.UIFigure, 'push');
            app.Buttonbase.ButtonPushedFcn = createCallbackFcn(app, @ButtonbaseButtonPushed);
            app.Buttonbase.Text = 'Base';
            app.stopbutton = uibutton(app.UIFigure, 'push');
            app.stopbutton.ButtonPushedFcn = createCallbackFcn(app, @stopbuttonButtonPushed);
            app.stopbutton.Text = 'stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DspGui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end